classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        JacoStatusTab                matlab.ui.container.Tab
        SortSpoonsSwitchLabel        matlab.ui.control.Label
        SortSpoonsSwitch             matlab.ui.control.Switch
        EMERGENCYSTOPButton          matlab.ui.control.Button
        MessagefromCutleryMagicianPanel  matlab.ui.container.Panel
        StatusBox                    matlab.ui.control.Label
        WarningsPanel                matlab.ui.container.Panel
        WarningsBox                  matlab.ui.control.Label
        SortForksSwitchLabel         matlab.ui.control.Label
        SortForksSwitch              matlab.ui.control.Switch
        SortKnivesSwitchLabel        matlab.ui.control.Label
        SortKnivesSwitch             matlab.ui.control.Switch
        ResumeButton                 matlab.ui.control.StateButton
        ResetButton                  matlab.ui.control.StateButton
        RobotConfigurationTab        matlab.ui.container.Tab
        q1SliderLabel                matlab.ui.control.Label
        q1Slider                     matlab.ui.control.Slider
        q2SliderLabel                matlab.ui.control.Label
        q2Slider                     matlab.ui.control.Slider
        q3SliderLabel                matlab.ui.control.Label
        q3Slider                     matlab.ui.control.Slider
        q4SliderLabel                matlab.ui.control.Label
        q4Slider                     matlab.ui.control.Slider
        q5SliderLabel                matlab.ui.control.Label
        q5Slider                     matlab.ui.control.Slider
        q6SliderLabel                matlab.ui.control.Label
        q6Slider                     matlab.ui.control.Slider
        JointAnglesLabel             matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditField                   matlab.ui.control.NumericEditField
        zEditField                   matlab.ui.control.NumericEditField
        CartesianMovementLabel       matlab.ui.control.Label
        xPlusButton                  matlab.ui.control.Button
        yPlusButton                  matlab.ui.control.Button
        zPlusButton                  matlab.ui.control.Button
        xMinusButton                 matlab.ui.control.Button
        yMinusButton                 matlab.ui.control.Button
        zMinusButton                 matlab.ui.control.Button
        xBox                         matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        yBox                         matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        zBox                         matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        OtherTab                     matlab.ui.container.Tab
        TestForcedCollisionButton    matlab.ui.control.StateButton
        TestSimulatedLightCurtainButton  matlab.ui.control.Button
        RetreatfromSafetySignButton  matlab.ui.control.Button
    end


    properties (Access = private)
         robot; % Jaco robot links and properties
         
         qHomePose = [pi/2 pi deg2rad(341) pi/2 2*pi 0];
         
         homeLoc = transl(2.3, 1.85, 0.7);
         
        spoonLoc = transl(1.65, 1.5, 0.6)*trotx(pi);
        spoonLocTwo = transl(1.6, 1.6, 0.6)*trotx(pi);
        spoonLocThree = transl(1.65, 1.3, 0.6)*trotx(pi);
        forkLoc = transl(1.75, 1.4, 0.6)*trotx(pi);
        %forkLocTwo = transl(1.6, 1.65, 0.6)*trotx(pi)*trotz(pi/2);
        %forkLocThree = transl(1.65, 1.7, 0.6)*trotx(pi);
        forkLocTwo = transl(1.7, 1.8, 0.6)*trotx(pi);
        forkLocThree = transl(1.8, 1.85, 0.6)*trotx(pi);
        knifeLoc = transl(1.6, 1.25, 0.6)*trotx(pi);
        knifeLocTwo = transl(1.68, 1.35, 0.6)*trotx(pi);
        knifeLocThree = transl(1.71, 1.7, 0.6)*trotx(pi);
        containerOneLoc = transl(1.6, 1.4, 0.6)*trotx(pi);
        containerTwoLoc = transl(1.5, 1.6, 0.6)*trotx(pi);
        containerThreeLoc = transl(1.6, 1.8, 0.6)*trotx(pi);

         eStopLoc = transl(2.6, 1.2, 0.6);            %Location needs to be fixed up, this is a random number5
         barrierLoc = transl(0.9, 2.1, 1.05);
         
         forcedCollisionLoc = transl(1.4, 2.5, 0.7)*trotx(pi);
         handLoc = transl(1.5, 0.3, 0.4);
         
         spoonMesh_h;
         spoonVertexCount;
         spoonVerts;
         spoonMeshTwo_h;
         spoonVertexCountTwo;
         spoonVertsTwo;
         spoonMeshThree_h;
         spoonVertexCountThree;
         spoonVertsThree;
       
         forkMesh_h;
         forkVertexCount;
         forkVerts;
         forkMeshTwo_h;
         forkVertexCountTwo;
         forkVertsTwo;
         forkMeshThree_h;
         forkVertexCountThree;
         forkVertsThree;
         
         knifeMesh_h;
         knifeVertexCount;
         knifeVerts;
         knifeMeshTwo_h;
         knifeVertexCountTwo;
         knifeVertsTwo;
         knifeMeshThree_h;
         knifeVertexCountThree;
         knifeVertsThree;
         
         benchMesh_h;
         eStopMesh_h;
         barrierMesh_h;
         handMesh;
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %When the GUI starts, create the robot and environment: Ready for simulation    
            uicontrol('Style', 'Text', 'String', 'Please wait');
            app.StatusBox.Text = "Please wait while I create my simulation :)" %Updates status box
            
            %This section loads the robot
            app.robot = Jaco();
            app.robot.GetJacoRobot();
            app.robot.PlotAndColourRobot();
            
            
            jacoBase = transl(2.3, 1.85, 0.7);
            app.robot.model.base = jacoBase;
            
            app.robot.model.plotopt = {'nojoints', 'noname', 'noshadow','nowrist'};
            app.robot.model.plot(app.qHomePose, 'scale', app.robot.scale, 'workspace', app.robot.workspace);      %Plot model         
            hold on;
            
            % This section will now load the environment
            xOffset = 0;
            yOffset = 0;
            zOffset = -0.5;
            [partMesh,f,v] = Environment('kitchen.ply', xOffset, yOffset, zOffset);
            app.benchMesh_h = partMesh;
            hold on;
            
            %Floor
            x = [- 6 -6; 6 6];
            y = [-2 2; -2 2];
            z = [-0.5 -0.5; -0.5 -0.5];
            floor_h = background('floor.jpeg', x, y, z);
            
            %Window Wall
            x = [4 -4; 4 -4];
            y = [2.2 2.2; 2.2 2.2];
            z = [2 2; -0.5 -0.5]; 
            window_h = background('windowWall.jpg', x, y, z);
            
            %Warning Sign
            x = [0.9 0.9; 0.9 0.9];
            y = [1.4 1.6; 1.4 1.6];
            z = [1.2 1.2; 1 1]; 
            warningSign_h = background('WarningSign.jpg', x, y, z);
            
            %Import containers
            %Locations of each container (hard coded):
            
            
            %ContainerOneLoc = transl(1.41, 1, 0.25);
            
            [partMesh, f, v] = Environment('Container1.ply', app.containerOneLoc(1,4), app.containerOneLoc(2,4), app.containerOneLoc(3,4));
            containerOneMesh_h = partMesh;
            containerOneFaces = f;
            containerOneVertices = v;
            % containerOneFN = containerOneMesh_h.FaceNormals;
            hold on;
            
            [partMesh, f, v] = Environment('Container2.ply', app.containerTwoLoc(1,4), app.containerTwoLoc(2,4), app.containerTwoLoc(3,4));
            containerTwoMesh_h = partMesh;
            containerTwoFaces = f;
            containerTwoVertices = v;
            % containerTwoFaceNorms = containerTwoMesh_h.FaceNormals;
            hold on;
            
            partMesh = Environment('Container3.ply', app.containerThreeLoc(1,4), app.containerThreeLoc(2,4), app.containerThreeLoc(3,4));
            containerThreeMesh_h = partMesh;
            containerThreeFaces = f;
            containerThreeVertices = v;
            % containerThreeFaceNorms = containerThreeMesh_h.FaceNormals;
            hold on;
            
            %Safety Features - i.e eStop, encasing, warning signs,
                        
            %eStop
            partMesh = Environment('eStop.ply', app.eStopLoc(1,4), app.eStopLoc(2,4), app.eStopLoc(3,4)); %Rescale Estop lol
            app.eStopMesh_h = partMesh;
            hold on;
            
            %Barrier
            partMesh = Environment('Wall.ply', app.barrierLoc(1,4), app.barrierLoc(2,4), app.barrierLoc(3,4));
            app.barrierMesh_h = partMesh;
            hold on;

            
            %light curtain
            for z = 0.7:0.05:1.1
            line('XData', [0.8 2.5], 'YData', [1.2 1.2], 'Zdata', [z z], 'Color', [1 0 0]);
            end
            
                       
            % Import Cutlery
            
            [spoonMesh, spoonVertexCount, spoonVerts] = PlotCutlery('Spoon.ply', app.spoonLoc(1,4), app.spoonLoc(2,4), app.spoonLoc(3,4));
            app.spoonMesh_h = spoonMesh;
            app.spoonVertexCount = spoonVertexCount;
            app.spoonVerts = spoonVerts;
                
            [spoonMesh, spoonVertexCountTwo, spoonVertsTwo] = PlotCutlery('Spoon.ply', app.spoonLocTwo(1,4), app.spoonLocTwo(2,4), app.spoonLocTwo(3,4));
            app.spoonMeshTwo_h = spoonMesh;
            app.spoonVertexCountTwo = spoonVertexCountTwo;
            app.spoonVertsTwo = spoonVertsTwo;

            [spoonMesh, spoonVertexCountThree, spoonVertsThree] = PlotCutlery('Spoon.ply', app.spoonLocThree(1,4), app.spoonLocThree(2,4), app.spoonLocThree(3,4));
            app.spoonMeshThree_h = spoonMesh; 
            app.spoonVertexCountThree = spoonVertexCountThree;
            app.spoonVertsThree = spoonVertsThree;
            
            [forkMesh, forkVertexCount, forkVerts] = PlotCutlery('Fork.ply', app.forkLoc(1,4), app.forkLoc(2,4), app.forkLoc(3,4));
            app.forkMesh_h = forkMesh;
            app.forkVertexCount = forkVertexCount;
            app.forkVerts = forkVerts;
            
            [forkMesh, forkVertexCountTwo, forkVertsTwo] = PlotCutlery('Fork.ply', app.forkLocTwo(1,4), app.forkLocTwo(2,4), app.forkLocTwo(3,4));
            app.forkMeshTwo_h = forkMesh;
            app.forkVertexCountTwo = forkVertexCountTwo;
            app.forkVerts = forkVertsTwo;
            
            [forkMesh, forkVertexCountThree, forkVertsThree] = PlotCutlery('Fork.ply', app.forkLocThree(1,4), app.forkLocThree(2,4), app.forkLocThree(3,4));
            app.forkMeshThree_h = forkMesh;
            app.forkVertexCountThree = forkVertexCountThree;
            app.forkVertsThree = forkVertsThree;
            
            [knifeMesh, knifeVertexCount, knifeVerts] = PlotCutlery('Knife.ply', app.knifeLoc(1,4), app.knifeLoc(2,4), app.knifeLoc(3,4));
            app.knifeMesh_h = knifeMesh;
            app.knifeVertexCount = knifeVertexCount;
            app.knifeVerts = knifeVerts;
            
            [knifeMesh, knifeVertexCountTwo, knifeVertsTwo] = PlotCutlery('Knife.ply', app.knifeLocTwo(1,4), app.knifeLocTwo(2,4), app.knifeLocTwo(3,4));
            app.knifeMeshTwo_h = knifeMesh;
            app.knifeVertexCountTwo = knifeVertexCountTwo;
            app.knifeVertsTwo = knifeVertsTwo;
            
            [knifeMesh, knifeVertexCountThree, knifeVertsThree] = PlotCutlery('Knife.ply', app.knifeLocThree(1,4), app.knifeLocThree(2,4), app.knifeLocThree(3,4));
            app.knifeMeshThree_h = knifeMesh;
            app.knifeVertexCountThree = knifeVertexCountThree;
            app.knifeVertsThree = knifeVertsThree;
                                             
            app.StatusBox.Text = "Hi! I am your Cutlery Magician. Press a switch to begin." %Updates status box
            uicontrol('Style', 'Text', 'String', 'Ready');
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 1;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
           
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 2;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 3;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 4;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 5;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;
            joint = 6;
            angle = changingValue;
            UpdateJointAngles(app.robot, joint, angle);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            cartesian = 'x';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            cartesian = 'y';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
            app.StatusBox.Text = "Updated Joint Angles"
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            value = app.zEditField.Value;
            cartesian = 'z';
            variable = value;
            UpdateCartesian(app.robot, cartesian, variable);
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.WarningsBox.Text = "EMERGENCY STOP BUTTON PUSHED"
            eStopButton = 1;
            uiwait;
        end

        % Value changed function: SortSpoonsSwitch
        function SwitchSpoonValueChanged(app, event)
            value = app.SortSpoonsSwitch.Value;
%             switchON = value;
%             if switchON == value;
            
            homePoseTr = app.robot.model.fkine(app.qHomePose);
            qSpoon = app.robot.model.ikcon(app.spoonLoc);
            qSpoonTwo = app.robot.model.ikcon(app.spoonLocTwo);
            qSpoonThree = app.robot.model.ikcon(app.spoonLocThree);
            qContainerThree = app.robot.model.ikcon(app.containerThreeLoc);
            
            containerThreeTr = app.robot.model.fkine(qContainerThree);
            spoonTr = app.robot.model.fkine(qSpoon);
            spoonTwoTr = app.robot.model.fkine(qSpoonTwo);
            spoonThreeTr = app.robot.model.fkine(qSpoonThree);
            
            app.StatusBox.Text = "Sorting Spoons"
            
%             rmrc(homePoseTr, app.spoonLoc, app.robot);
%             rmrcObject(spoonTr, app.containerThreeLoc, app.robot, app.spoonVerts, app.spoonVertexCount, app.spoonMesh_h);
%             rmrc(containerThreeTr, app.spoonLocTwo, app.robot);
%             rmrcObject(spoonTwoTr, app.containerThreeLoc, app.robot, app.spoonVertsTwo, app.spoonVertexCountTwo, app.spoonMeshTwo_h);
%             rmrc(containerThreeTr, app.spoonLocThree, app.robot);
%             rmrcObject(spoonThreeTr, app.containerThreeLoc, app.robot, app.spoonVertsThree, app.spoonVertexCountThree, app.spoonMeshThree_h);
%             rmrc(containerThreeTr, app.homeLoc, app.robot);
%                         
            rmrc(homePoseTr, app.spoonLoc, app.robot);
            objectMove(qSpoon, qContainerThree, app.robot, app.spoonVerts, app.spoonVertexCount, app.spoonMesh_h);
            rmrc(containerThreeTr, app.spoonLocTwo, app.robot);
            objectMove(qSpoonTwo, qContainerThree, app.robot, app.spoonVertsTwo, app.spoonVertexCountTwo, app.spoonMeshTwo_h);
            rmrc(containerThreeTr, app.spoonLocThree, app.robot);
            objectMove(qSpoonThree, qContainerThree, app.robot, app.spoonVertsThree, app.spoonVertexCountThree, app.spoonMeshThree_h);
            rmrc(containerThreeTr, app.homeLoc, app.robot);

            
        end

        % Value changed function: SortForksSwitch
        function SortForksSwitchValueChanged(app, event)
            value = app.SortForksSwitch.Value;
            
            homePoseTr = app.robot.model.fkine(app.qHomePose);
            qFork = app.robot.model.ikcon(app.forkLoc);
            qForkTwo = app.robot.model.ikcon(app.forkLocTwo);
            qForkThree = app.robot.model.ikcon(app.forkLocThree);
            qContainerTwo = app.robot.model.ikcon(app.containerTwoLoc);
            
            containerTwoTr = app.robot.model.fkine(qContainerTwo);
            forkTr = app.robot.model.fkine(qFork);
            forkTwoTr = app.robot.model.fkine(qForkTwo);
            forkThreeTr = app.robot.model.fkine(qForkThree);
                        
            app.StatusBox.Text = "Sorting Forks"
            
%             rmrc(homePoseTr, app.forkLoc, app.robot);
%             rmrcObject(forkTr, app.containerTwoLoc, app.robot, app.forkVerts, app.forkVertexCount, app.forkMesh_h);
%             rmrc(containerTwoTr, app.forkLocTwo, app.robot);
%             rmrcObject(forkTwoTr, app.containerTwoLoc, app.robot, app.forkVertsTwo, app.forkVertexCountTwo, app.forkMeshTwo_h);
%             rmrc(containerTwoTr, app.forkLocThree, app.robot);
%             rmrcObject(forkThreeTr, app.containerTwoLoc, app.robot, app.forkVertsThree, app.forkVertexCountThree, app.forkMeshThree_h);
%             rmrc(containerTwoTr, app.homeLoc, app.robot);
            
            rmrc(app.homePoseTr, app.forkLoc, app.robot);
            objectMove(qFork, qContainerTwo, app.robot, app.forkVerts, app.forkVertexCount, app.forkMesh_h);
            rmrc(containerTwoTr, app.forkLocTwo, app.robot);
            objectMove(qForkTwo, qContainerTwo, app.robot, app.forkVertsTwo, app.forkVertexCountTwo, app.forkMeshTwo_h);
            rmrc(containerTwoTr, app.forkLocThree, app.robot);
            objectMove(qForkThree, qContainerTwo, app.robot, app.forkVertsThree, app.forkVertexCountThree, app.forkMeshThree_h);
            rmrc(containerTwoTr, app.homeLoc, app.robot);


        end

        % Value changed function: SortKnivesSwitch
        function SortKnivesSwitchValueChanged(app, event)
            value = app.SortKnivesSwitch.Value;
            
            homePoseTr = app.robot.model.fkine(app.qHomePose);
            qKnife = app.robot.model.ikcon(app.knifeLoc);
            qKnifeTwo = app.robot.model.ikcon(app.knifeLocTwo);
            qKnifeThree = app.robot.model.ikcon(app.knifeLocThree);
            qContainerOne = app.robot.model.ikcon(app.containerOneLoc);
            
            containerOneTr = app.robot.model.fkine(qContainerOne);
            knifeTr = app.robot.model.fkine(qKnife);
            knifeTwoTr = app.robot.model.fkine(qKnifeTwo);
            knifeThreeTr = app.robot.model.fkine(qKnifeThree);
                        
            app.StatusBox.Text = "Sorting Knives"
            
%              rmrc(homePoseTr, app.knifeLoc, app.robot);
%              rmrcObject(knifeTr, app.containerOneLoc, app.robot, app.knifeVerts, app.knifeVertexCount, app.knifeMesh_h);
%              rmrc(containerOneTr, app.knifeLocTwo, app.robot);
%              rmrcObject(knifeTwoTr, app.containerOneLoc, app.robot, app.knifeVertsTwo, app.knifeVertexCountTwo, app.knifeMeshTwo_h);
%              rmrc(containerOneTr, app.knifeLocThree, app.robot);
%              rmrcObject(knifeThreeTr, app.containerOneLoc, app.robot, app.knifeVertsThree, app.knifeVertexCountThree, app.knifeMeshThree_h);
%              rmrc(containerOneTr, app.homePose, app.robot);
            
            rmrc(homePoseTr, app.knifeLoc, app.robot);
            objectMove(qKnife, qContainerOne, app.robot, app.knifeVerts, app.knifeVertexCount, app.knifeMesh_h);
            rmrc(containerOneTr, app.knifeLocTwo, app.robot);
            objectMove(qKnifeTwo, qContainerOne, app.robot, app.knifeVertsTwo, app.knifeVertexCountTwo, app.knifeMeshTwo_h);
             rmrc(containerOneTr, app.knifeLocThree, app.robot);
            objectMove(qKnifeThree, qContainerOne, app.robot, app.knifeVertsThree, app.knifeVertexCountThree, app.knifeMeshThree_h);
             rmrc(containerOneTr, app.homePose, app.robot);
    
        end

        % Value changed function: ResumeButton
        function ResumeButtonValueChanged(app, event)
            value = app.ResumeButton.Value;
            uiresume;
            app.WarningsBox.Text = "Resumed"
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
            app.WarningsBox.Text = "Beware of moving robot"
            app.StatusBox.Text = "- Cutlery Magician resetting -"
            delete(app.handMesh);  
            qCollision = app.robot.model.getpos();
            jacoMove(qCollision, app.qHomePose, app.robot);
            app.WarningsBox.Text = " "
            app.StatusBox.Text = "Hi! I am the Cutlery Magician. Press a switch to begin."
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: TestSimulatedLightCurtainButton
        function TestSimulatedLightCurtainButtonPushed(app, event)
               startPoints = [];
                for x = 0.8:0.1:1.4
                    for y = 1.2
                        for z = 0.7:0.05:0.9
                            startPoints = [startPoints; x y z];
                        end
                        
                    end
                    
                end
                
                finishPoints = [];
                for x = 1.4:0.1:2.5
                    for y = 1.2
                        for z = 0.9:0.05:1.1
                            finishPoints = [finishPoints; x y z];
                        end
                        
                    end
                    
                end  
                
        qContainerOne = app.robot.model.ikcon(app.containerOneLoc);
        app.StatusBox.Text = "Light Curtain Activated"
        qStart = app.robot.model.getpos();
        app.WarningsBox.Text = "No collisions: Robot safely moving ~"
        [statusFlag, handMesh_h] = jacoMoveTest(qStart, qContainerOne, app.robot, startPoints, finishPoints);
        app.handMesh = handMesh_h;
        if statusFlag == 1;
          app.WarningsBox.Text = "Collision Detected!!" 
          app.StatusBox.Text = "Robot has paused: Press Reset"
        end

        end

        % Value changed function: TestForcedCollisionButton
        function TestForcedCollisionButtonValueChanged(app, event)
            value = app.TestForcedCollisionButton.Value;
                                       
            app.StatusBox.Text = "Forced Collision Simulation"
                        
            qForcedCollision = app.robot.model.ikcon(app.forcedCollisionLoc);
            flag = jacoMoveToCollision(app.qHomePose, qForcedCollision, app.robot, app.barrierMesh_h);
            if flag == 1;
                app.WarningsBox.Text = "Collision Detected!!"
                app.StatusBox.Text = "Robot has paused: Press Reset"
            end
            
        
        end

        % Button pushed function: RetreatfromSafetySignButton
        function RetreatfromSafetySignButtonPushed(app, event)
pWarning = [310 510 610; 610 250 620];
targetPoints = [0.8 0.8 0.8;
                1.35 1.5 1.65; 
                1.1 01.5 1.1];
%qInitial = [0 pi deg2rad(341) deg2rad(200) -pi/2 pi];
qInitial = [0 pi pi deg2rad(-238) deg2rad(133) deg2rad(150)];
cam = CentralCamera('focal', 0.08', 'pixel', 10e-5, ...
    'resolution', [1024 1024], 'centre', [500 500], 'name', 'CM Cam');
fps = 100;
lambda = 0.6 %Gain
%depth = mean (targetPoints(1,:));
depth = 1;
% Initialise visual servo sim 3d display
jcTr = app.robot.model.fkine(qInitial); %Transform of EE initial pos
app.robot.model.animate(qInitial);
%drawnow

cam.T = jcTr; %Plots cam 
cam.plot_camera('Tcam', jcTr, 'label', 'scale', 0.15);%Plot cam on EE
plot_sphere(targetPoints, 0.01, 'y');%Display points in 3d
% Initialise sim image view display
p = cam.plot(targetPoints, 'Tcam', jcTr); %project points to image (current view of cam)

%cam.clf();
cam.plot(pWarning, '*');%Plot desired view
cam.hold(true);
cam.plot(targetPoints, 'Tcam', jcTr, 'o');%What the camera Sees
pause(2);
% cam.hold(true);
% cam.plot(targetPoints); %Plot initial View

vel_p = [];
uv_p = [];
history = [];

%Visual Servo Loop 

ksteps = 0;
while true
    ksteps=ksteps +1;
    uv = cam.plot(targetPoints); %compute view of cam
    e = pWarning - uv; %feature error
    e = e(:);
    Zest = [];
    %compute jacobian
    if isempty(depth)
        %exact depth from sim
        pt = homtrans(inv(Tcam), targetPoints);
        J = cam.visjac_p(uv, pt(3,:));
    elseif ~isempty(Zest)
        J = cam.visjac_p(uv, Zest);
    else 
        J = cam.visjac_p(uv, depth);
    end
     %compute velocity of cam in cam frame
    try
        v = lambda*pinv(J) * e;
    catch
        status = -1;
        return
    end
    %fprintf('v: %.3f %.3f %.3f %.3f %.3f %.3f\n', v);
    %compute robot jacobian and inverse
    J2 = app.robot.model.jacobn(qInitial);
    Jinv = pinv(J2);
    qp = Jinv*v; %get joint velocities
    %max angular velocity cannot exceed 180 degs
    ind = find(qp > pi);
    if ~isempty(ind)
        qp(ind) = pi;
    end
    ind = find(qp <- pi);
    if ~isempty(ind)
        qp(ind)=-pi;
    end
        q = qInitial + (1/fps) * qp'; %Update joints
        app.robot.model.animate(q);
        Tc = app.robot.model.fkine(q); %Get cam Location
        cam.T = Tc;
        drawnow
        %Update history variables
        hist.uv = uv(:);
        vel = v;
        hist.vel = vel;
        hist.e = e;
        hist.en = norm(e);
        hist.jcond = cond(J);
        hist.Tcam = Tc;
        hist.vel_p = vel;
        hist.uv_p = uv;
        hist.qp = qp;
        hist.q = q;
        history = [history hist];
        pause(1/fps)
        
        if ~isempty(200)&& (ksteps > 200)
            break;
        end
        
        qInitial = q;%update current joint position
end
        end

        % Button pushed function: xPlusButton
        function xPlusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(1,4) = EEtransform(1,4)+0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.xBox.Text = string(EEtransform(1,4));
        end

        % Button pushed function: yPlusButton
        function yPlusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(2,4) = EEtransform(2,4)+0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.yBox.Text = string(EEtransform(2,4));
            
        end

        % Button pushed function: zPlusButton
        function zPlusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(3,4) = EEtransform(3,4)+0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.zBox.Text = string(EEtransform(3,4));
        end

        % Button pushed function: xMinusButton
        function xMinusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(1,4) = EEtransform(1,4)-0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.xBox.Text = string(EEtransform(1,4));
        end

        % Button pushed function: yMinusButton
        function yMinusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(2,4) = EEtransform(2,4)-0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.yBox.Text = string(EEtransform(2,4));
        end

        % Button pushed function: zMinusButton
        function zMinusButtonPushed(app, event)
            qCurrent = app.robot.model.getpos();
            EEtransform = app.robot.model.fkine(qCurrent);
            EEtransform(3,4) = EEtransform(3,4)-0.5;
            qNew = app.robot.model.ikcon(EEtransform);  
            app.robot.model.animate(qNew);
            app.zBox.Text = string(EEtransform(3,4));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JacoStatusTab = uitab(app.TabGroup);
            app.JacoStatusTab.Title = 'Jaco Status';
            app.SortSpoonsSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortSpoonsSwitchLabel.Text = 'Sort Spoons';
            app.SortSpoonsSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortSpoonsSwitch.Items = {'', ''};
            app.SortSpoonsSwitch.ValueChangedFcn = createCallbackFcn(app, @SwitchSpoonValueChanged, true);
            app.SortSpoonsSwitch.Value = '';
            app.EMERGENCYSTOPButton = uibutton(app.JacoStatusTab, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY '; 'STOP'};
            app.MessagefromCutleryMagicianPanel = uipanel(app.JacoStatusTab);
            app.MessagefromCutleryMagicianPanel.Title = 'Message from Cutlery Magician:';
            app.StatusBox = uilabel(app.MessagefromCutleryMagicianPanel);
            app.StatusBox.Text = '';
            app.WarningsPanel = uipanel(app.JacoStatusTab);
            app.WarningsPanel.Title = 'Warnings:';
            app.WarningsBox = uilabel(app.WarningsPanel);
            app.WarningsBox.Text = '';
            app.SortForksSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortForksSwitchLabel.Text = 'Sort Forks';
            app.SortForksSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortForksSwitch.Items = {'', ''};
            app.SortForksSwitch.ValueChangedFcn = createCallbackFcn(app, @SortForksSwitchValueChanged, true);
            app.SortForksSwitch.Value = '';
            app.SortKnivesSwitchLabel = uilabel(app.JacoStatusTab);
            app.SortKnivesSwitchLabel.Text = 'Sort Knives';
            app.SortKnivesSwitch = uiswitch(app.JacoStatusTab, 'slider');
            app.SortKnivesSwitch.Items = {'', ''};
            app.SortKnivesSwitch.ValueChangedFcn = createCallbackFcn(app, @SortKnivesSwitchValueChanged, true);
            app.SortKnivesSwitch.Value = '';
            app.ResumeButton = uibutton(app.JacoStatusTab, 'state');
            app.ResumeButton.ValueChangedFcn = createCallbackFcn(app, @ResumeButtonValueChanged, true);
            app.ResumeButton.Text = 'Resume';
            app.ResetButton = uibutton(app.JacoStatusTab, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.RobotConfigurationTab = uitab(app.TabGroup);
            app.RobotConfigurationTab.Title = 'Robot Configuration';
            app.q1SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.RobotConfigurationTab);
            app.q1Slider.Limits = [-360 360];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q1Slider.MinorTicks = [-360 -340 -320 -300 -280 -260 -240 -220 -200 -180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360];
            app.q2SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.RobotConfigurationTab);
            app.q2Slider.Limits = [47 313];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.Value = 47;
            app.q3SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.RobotConfigurationTab);
            app.q3Slider.Limits = [19 341];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.Value = 19;
            app.q4SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.RobotConfigurationTab);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.RobotConfigurationTab);
            app.q5Slider.Limits = [65 295];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.Value = 65;
            app.q6SliderLabel = uilabel(app.RobotConfigurationTab);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.RobotConfigurationTab);
            app.q6Slider.Limits = [-360 360];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.JointAnglesLabel = uilabel(app.RobotConfigurationTab);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.xEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditField = uieditfield(app.RobotConfigurationTab, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.CartesianMovementLabel = uilabel(app.RobotConfigurationTab);
            app.CartesianMovementLabel.Text = 'Cartesian Movement';
            app.xPlusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.xPlusButton.ButtonPushedFcn = createCallbackFcn(app, @xPlusButtonPushed, true);
            app.xPlusButton.Text = '+ 0.5';
            app.yPlusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.yPlusButton.ButtonPushedFcn = createCallbackFcn(app, @yPlusButtonPushed, true);
            app.yPlusButton.Text = '+ 0.5';
            app.zPlusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.zPlusButton.ButtonPushedFcn = createCallbackFcn(app, @zPlusButtonPushed, true);
            app.zPlusButton.Text = '+ 0.5';
            app.xMinusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.xMinusButton.ButtonPushedFcn = createCallbackFcn(app, @xMinusButtonPushed, true);
            app.xMinusButton.Text = '- 0.5';
            app.yMinusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.yMinusButton.ButtonPushedFcn = createCallbackFcn(app, @yMinusButtonPushed, true);
            app.yMinusButton.Text = '- 0.5';
            app.zMinusButton = uibutton(app.RobotConfigurationTab, 'push');
            app.zMinusButton.ButtonPushedFcn = createCallbackFcn(app, @zMinusButtonPushed, true);
            app.zMinusButton.Text = '- 0.5';
            app.xBox = uilabel(app.RobotConfigurationTab);
            app.xBox.Text = '';
            app.XLabel = uilabel(app.RobotConfigurationTab);
            app.XLabel.Text = 'X';
            app.yBox = uilabel(app.RobotConfigurationTab);
            app.yBox.Text = '';
            app.YLabel = uilabel(app.RobotConfigurationTab);
            app.YLabel.Text = 'Y';
            app.zBox = uilabel(app.RobotConfigurationTab);
            app.zBox.Text = '';
            app.ZLabel = uilabel(app.RobotConfigurationTab);
            app.ZLabel.Text = 'Z';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.TestForcedCollisionButton = uibutton(app.OtherTab, 'state');
            app.TestForcedCollisionButton.ValueChangedFcn = createCallbackFcn(app, @TestForcedCollisionButtonValueChanged, true);
            app.TestForcedCollisionButton.Text = 'Test Forced Collision';
            app.TestSimulatedLightCurtainButton = uibutton(app.OtherTab, 'push');
            app.TestSimulatedLightCurtainButton.ButtonPushedFcn = createCallbackFcn(app, @TestSimulatedLightCurtainButtonPushed, true);
            app.TestSimulatedLightCurtainButton.Text = 'Test Simulated Light Curtain';
            app.RetreatfromSafetySignButton = uibutton(app.OtherTab, 'push');
            app.RetreatfromSafetySignButton.ButtonPushedFcn = createCallbackFcn(app, @RetreatfromSafetySignButtonPushed, true);
            app.RetreatfromSafetySignButton.Text = 'Retreat from Safety Sign';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end